classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        eSTOPButton              matlab.ui.control.Button
        STOPPEDLamp              matlab.ui.control.Lamp
        STOPPEDLampLabel         matlab.ui.control.Label
        StartLamp                matlab.ui.control.Lamp
        StartLampLabel           matlab.ui.control.Label
        ReadyLamp                matlab.ui.control.Lamp
        ReadyLampLabel           matlab.ui.control.Label
        eStopRestartButtonGroup  matlab.ui.container.ButtonGroup
        StartButton              matlab.ui.control.Button
        ReadyButton              matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        simObj;
        systemStopped = false;
        systemReady = false;
    end
    
   methods (Access = public)
        function setSimulationInstance(app, simulationInstance)
        app.simObj = simulationInstance;
        disp("GUI HAS BEEN HIT");
        app.updateLamps('running');
        end
        
        function updateLamps(app, state)
            switch state
                case 'stopped'
                    app.STOPPEDLamp.Color = [1 0 0]; % Red
                    app.ReadyLamp.Color = [0.5 0.5 0.5]; % Off
                    app.StartLamp.Color = [0.5 0.5 0.5]; % Off
                case 'ready'
                    app.STOPPEDLamp.Color = [1 0 0]; % Red
                    app.ReadyLamp.Color = [0.8 0.8 0]; % Yellow
                    app.StartLamp.Color = [0.5 0.5 0.5]; % Off
                case 'running'
                    app.STOPPEDLamp.Color = [0.5 0.5 0.5]; % Off
                    app.ReadyLamp.Color = [0.5 0.5 0.5]; % Off
                    app.StartLamp.Color = [0 1 0]; % Green
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: eSTOPButton
        function eSTOPButtonPushed(app, event)
            fprintf('eSTOP in Gui\n');
            app.systemStopped = true; % Trigger eStop
            app.updateLamps('stopped'); % Update lamp states
            if ~isempty(app.simObj)
            app.simObj.handleEStop();
            end
        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
             if app.systemStopped
                fprintf('READY in Gui\n');
                app.systemReady = true; % Set system to ready state
                app.updateLamps('ready');
                if ~isempty(app.simObj)
                app.simObj.handleReady();
                end
             end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if  app.systemStopped && app.systemReady
                fprintf('START in Gui\n');
                app.updateLamps('running');
                app.systemStopped = false; % Resume from eStop
                if ~isempty(app.simObj)
                app.simObj.handleStart();
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {234, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.eStopRestartButtonGroup = uibuttongroup(app.LeftPanel);
            app.eStopRestartButtonGroup.Title = 'eStop Restart';
            app.ReadyButton = uibutton(app.eStopRestartButtonGroup, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'Ready';
            app.StartButton = uibutton(app.eStopRestartButtonGroup, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ReadyLampLabel = uilabel(app.LeftPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.LeftPanel);
            app.StartLampLabel = uilabel(app.LeftPanel);
            app.StartLampLabel.Text = 'Start';
            app.StartLamp = uilamp(app.LeftPanel);
            app.STOPPEDLampLabel = uilabel(app.LeftPanel);
            app.STOPPEDLampLabel.Text = 'STOPPED';
            app.STOPPEDLamp = uilamp(app.LeftPanel);
            app.eSTOPButton = uibutton(app.LeftPanel, 'push');
            app.eSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @eSTOPButtonPushed, true);
            app.eSTOPButton.Text = 'eSTOP';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end